classdef TestApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        LabelDropDown       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        DropDown2           matlab.ui.control.DropDown
        Button2             matlab.ui.control.Button
        Button3             matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        NumericEditField    matlab.ui.control.NumericEditField
        NumericEditField2   matlab.ui.control.NumericEditField
        NumericEditField3   matlab.ui.control.NumericEditField
        Button4             matlab.ui.control.Button
        Panel2              matlab.ui.container.Panel
        NumericEditField4   matlab.ui.control.NumericEditField
        NumericEditField5   matlab.ui.control.NumericEditField
        NumericEditField6   matlab.ui.control.NumericEditField
        Panel3              matlab.ui.container.Panel
        ChosedatasetLabel   matlab.ui.control.Label
        DropDown3           matlab.ui.control.DropDown
        CheckBox            matlab.ui.control.CheckBox
        CheckBox2           matlab.ui.control.CheckBox
        CheckBox7           matlab.ui.control.CheckBox
        CheckBox8           matlab.ui.control.CheckBox
        TextArea            matlab.ui.control.TextArea
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        Button5             matlab.ui.control.Button
        Button6             matlab.ui.control.Button
        Panel4              matlab.ui.container.Panel
        NumericEditField7   matlab.ui.control.NumericEditField
        NumericEditField8   matlab.ui.control.NumericEditField
        NumericEditField9   matlab.ui.control.NumericEditField
        Panel5              matlab.ui.container.Panel
        NumericEditField10  matlab.ui.control.NumericEditField
        NumericEditField11  matlab.ui.control.NumericEditField
        NumericEditField12  matlab.ui.control.NumericEditField
        Label2              matlab.ui.control.Label
        DropDown4           matlab.ui.control.DropDown
        Label3              matlab.ui.control.Label
        DropDown5           matlab.ui.control.DropDown
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes5             matlab.ui.control.UIAxes
        CheckBox3           matlab.ui.control.CheckBox
        CheckBox4           matlab.ui.control.CheckBox
        CheckBox5           matlab.ui.control.CheckBox
        CheckBox6           matlab.ui.control.CheckBox
        IncludeintestLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AHRS1; % Description
        AHRS2;
        
        AHRS3;
        AHRS4;
        
        errorProgress1;
        intProg1=1;
        errorProgress2;
        intProg2=1;
        
        errorProgress3;
        intProg3=1;
        errorProgress4;
        intProg4=1;
        
        stopOpt=0;
        
        dataset=1;
        RMS=0;
        IMU=0;
        exceptPeak=0;
        
        u1;
        u2;
        u3;
        u4;
        
        M1;
        M2;
        M3;
        M4;
        
        optimAttempts=0;
        optimFreezeParam=0;
    end
    
    methods (Access = private)
        
        function valChange1(app,src,event)
            className=class(app.AHRS1);
            switch(className)
                case 'MadgwickAHRS3'
                    app.AHRS1.Beta=app.NumericEditField.Value;
                case 'MadgwickAHRSclanek'
                    app.AHRS1.Beta=app.NumericEditField.Value;
                case 'JustaAHRSPureFast'
                    app.AHRS1.gain=app.NumericEditField.Value;
                    app.AHRS1.wAcc=app.NumericEditField2.Value;
                    app.AHRS1.wMag=app.NumericEditField3.Value;
                case 'Valenti_AHRS'
                    app.AHRS1.wAcc=app.NumericEditField.Value;
                    app.AHRS1.wMag=app.NumericEditField2.Value;
                case 'JustaAHRSPureFastConstantCorr'
                    app.AHRS1.wAcc=app.NumericEditField.Value;
                    app.AHRS1.wMag=app.NumericEditField2.Value;
                case 'JustaAHRSPureFastLinearCorr'
                    app.AHRS1.gain=app.NumericEditField.Value;
                    app.AHRS1.wAcc=app.NumericEditField2.Value;
                    app.AHRS1.wMag=app.NumericEditField3.Value;
                case 'JustaAHRSPure'
                    app.AHRS1.wAcc=app.NumericEditField.Value;
                    app.AHRS1.wMag=app.NumericEditField2.Value;
                case 'JinWuKF_AHRSreal2'
                    app.AHRS1.Sigma_g=diag([1 1 1])*app.NumericEditField.Value;
                    app.AHRS1.Sigma_a=diag([1 1 1])*app.NumericEditField2.Value;
                    app.AHRS1.Sigma_m=diag([1 1 1])*app.NumericEditField3.Value;
                case 'YoungSooSuh_AHRS'
                    app.AHRS1.rg=app.NumericEditField.Value;
                    app.AHRS1.ra=app.NumericEditField2.Value;
            end
        end
        
        function valChange2(app,src,event)
            className=class(app.AHRS2);
            switch(className)
                case 'MadgwickAHRS3'
                    app.AHRS2.Beta=app.NumericEditField4.Value;
                case 'MadgwickAHRSclanek'
                    app.AHRS2.Beta=app.NumericEditField4.Value;
                case 'JustaAHRSPureFast'
                    app.AHRS2.gain=app.NumericEditField4.Value;
                    app.AHRS2.wAcc=app.NumericEditField5.Value;
                    app.AHRS2.wMag=app.NumericEditField6.Value;
                case 'JustaAHRSPureFastConstantCorr'
                    app.AHRS2.wAcc=app.NumericEditField4.Value;
                    app.AHRS2.wMag=app.NumericEditField5.Value;
                case 'JustaAHRSPureFastLinearCorr'
                    app.AHRS2.gain=app.NumericEditField4.Value;
                    app.AHRS2.wAcc=app.NumericEditField5.Value;
                    app.AHRS2.wMag=app.NumericEditField6.Value;
                case 'JustaAHRSPure'
                    app.AHRS2.wAcc=app.NumericEditField4.Value;
                    app.AHRS2.wMag=app.NumericEditField5.Value;
                case 'Valenti_AHRS'
                    app.AHRS2.wAcc=app.NumericEditField4.Value;
                    app.AHRS2.wMag=app.NumericEditField5.Value;
                case 'JinWuKF_AHRSreal2'
                    app.AHRS2.Sigma_g=diag([1 1 1])*app.NumericEditField.Value;
                    app.AHRS2.Sigma_a=diag([1 1 1])*app.NumericEditField2.Value;
                    app.AHRS2.Sigma_m=diag([1 1 1])*app.NumericEditField3.Value;
                case 'YoungSooSuh_AHRS'
                    app.AHRS1.rg=app.NumericEditField.Value;
                    app.AHRS1.ra=app.NumericEditField2.Value;
            end
            
        end
        
        function valChange3(app,src,event)
            className=class(app.AHRS3);
            switch(className)
                case 'MadgwickAHRS3'
                    app.AHRS3.Beta=app.NumericEditField7.Value;
                case 'MadgwickAHRSclanek'
                    app.AHRS3.Beta=app.NumericEditField7.Value;
                case 'JustaAHRSPureFast'
                    app.AHRS3.gain=app.NumericEditField7.Value;
                    app.AHRS3.wAcc=app.NumericEditField8.Value;
                    app.AHRS3.wMag=app.NumericEditField9.Value;
                case 'JustaAHRSPureFastConstantCorr'
                    app.AHRS3.wAcc=app.NumericEditField7.Value;
                    app.AHRS1.wMag=app.NumericEditField8.Value;
                case 'JustaAHRSPureFastLinearCorr'
                    app.AHRS3.gain=app.NumericEditField7.Value;
                    app.AHRS3.wAcc=app.NumericEditField8.Value;
                    app.AHRS3.wMag=app.NumericEditField9.Value;
                case 'JustaAHRSPure'
                    app.AHRS3.wAcc=app.NumericEditField7.Value;
                    app.AHRS3.wMag=app.NumericEditField8.Value;
                case 'Valenti_AHRS'
                    app.AHRS3.wAcc=app.NumericEditField7.Value;
                    app.AHRS3.wMag=app.NumericEditField8.Value;
                case 'JinWuKF_AHRSreal2'
                    app.AHRS3.Sigma_g=diag([1 1 1]);
                    app.AHRS3.Sigma_a=diag([1 1 1])*app.NumericEditField7.Value;
                    app.AHRS3.Sigma_m=diag([1 1 1])*app.NumericEditField8.Value;
            end
            
        end
        
        function valChange4(app,src,event)
            className=class(app.AHRS4);
            switch(className)
                case 'MadgwickAHRS3'
                    app.AHRS4.Beta=app.NumericEditField10.Value;
                case 'MadgwickAHRSclanek'
                    app.AHRS4.Beta=app.NumericEditField10.Value;
                case 'JustaAHRSPureFast'
                    app.AHRS4.gain=app.NumericEditField10.Value;
                    app.AHRS4.wAcc=app.NumericEditField11.Value;
                    app.AHRS4.wMag=app.NumericEditField12.Value;
                case 'JustaAHRSPureFastConstantCorr'
                    app.AHRS4.wAcc=app.NumericEditField10.Value;
                    app.AHRS4.wMag=app.NumericEditField11.Value;
                case 'JustaAHRSPureFastLinearCorr'
                    app.AHRS4.gain=app.NumericEditField10.Value;
                    app.AHRS4.wAcc=app.NumericEditField11.Value;
                    app.AHRS4.wMag=app.NumericEditField12.Value;
                case 'JustaAHRSPure'
                    app.AHRS4.wAcc=app.NumericEditField10.Value;
                    app.AHRS4.wMag=app.NumericEditField11.Value;
                case 'Valenti_AHRS'
                    app.AHRS4.wAcc=app.NumericEditField10.Value;
                    app.AHRS4.wMag=app.NumericEditField11.Value;
                case 'JinWuKF_AHRSreal2'
                    app.AHRS4.Sigma_g=diag([1 1 1])*1;
                    app.AHRS4.Sigma_a=diag([1 1 1])*app.NumericEditField10.Value;
                    app.AHRS4.Sigma_m=diag([1 1 1])*app.NumericEditField11.Value;
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AHRS1 = MadgwickAHRS3();
            
            %             param1M1=uieditfield(app.Panel,'Position', [0 0 60 30]);
            %             param1M1.ValueChangedFcn=@app.mybuttonpress;
            %             param1M1.Value = num2str(app.AHRS1.Beta);
            
            app.AHRS2 = JustaAHRSPureFast();
            
            app.AHRS3 = Valenti_AHRS();
            app.AHRS4 = JinWuKF_AHRSreal2();
            
            
            app.NumericEditField.Value=app.AHRS1.Beta;
            app.NumericEditField4.Value=app.AHRS2.gain;
            app.NumericEditField5.Value=app.AHRS2.wAcc;
            app.NumericEditField6.Value=app.AHRS2.wMag;
            app.NumericEditField7.Value=app.AHRS3.wAcc;
            app.NumericEditField8.Value=app.AHRS3.wMag;
            app.NumericEditField10.Value=app.AHRS4.Sigma_a(1,1);
            app.NumericEditField11.Value=app.AHRS4.Sigma_m(1,1);
            
            app.NumericEditField.ValueChangedFcn=@app.valChange1;
            app.NumericEditField2.ValueChangedFcn=@app.valChange1;
            app.NumericEditField3.ValueChangedFcn=@app.valChange1;
            app.NumericEditField4.ValueChangedFcn=@app.valChange2;
            app.NumericEditField5.ValueChangedFcn=@app.valChange2;
            app.NumericEditField6.ValueChangedFcn=@app.valChange2;
            
            app.NumericEditField7.ValueChangedFcn=@app.valChange3;
            app.NumericEditField8.ValueChangedFcn=@app.valChange3;
            app.NumericEditField9.ValueChangedFcn=@app.valChange3;
            app.NumericEditField10.ValueChangedFcn=@app.valChange4;
            app.NumericEditField11.ValueChangedFcn=@app.valChange4;
            app.NumericEditField12.ValueChangedFcn=@app.valChange4;
            
            %             param1M2=uieditfield(app.Panel2,'Position', [0 0 60 30]);
            %             param1M2.ValueChangedFcn=@app.mybuttonpress;
            %             param2M2=uieditfield(app.Panel2,'Position', [80 0 60 30]);
            %             param2M2.ValueChangedFcn=@app.mybuttonpress;
            %             param3M2=uieditfield(app.Panel2,'Position', [160 0 60 30]);
            %             param3M2.ValueChangedFcn=@app.mybuttonpress;
        end

        % Button pushed function: Button
        function startApp(app, event)
            %             [app.M1,app.M2,app.u1,app.u2]=datasetTestingFunction(app.AHRS1,app.AHRS2,app.dataset,app.RMS);
            %             if(app.M1<app.M2)
            %                 res=['Mean error method 1: ', num2str(app.M1),char(10),'Mean error method 2: ',...
            %                     num2str(app.M2),char(10),'Method1 error is lower by:', num2str(100*(1-(app.M1/app.M2))),'%'];
            %             else
            %                 res=['Mean error method 1: ', num2str(app.M1),char(10),'Mean error method 2: ', ...
            %                     num2str(app.M2),char(10),'Method2 error is lower by:', num2str(100*(1-(app.M2/app.M1))),'%'];
            %             end
            
            evalin('base','clear');
            enabled=[0 0 0 0];
            if(app.CheckBox3.Value)
                enabled(1)=1;
            end
            if(app.CheckBox4.Value)
                enabled(2)=1;
            end
            if(app.CheckBox5.Value)
                enabled(3)=1;
            end
            if(app.CheckBox6.Value)
                enabled(4)=1;
            end
            
            res = {''};
            
            
            for i=1:4
                if(enabled(i))
                    switch(i)
                        case 1
                            [app.M1,app.u1,time]=datasetTestingFunctionSingle(app.AHRS1,app.dataset,app.RMS,app.IMU, app.exceptPeak);
                            res{end+1} =strcat('Method 1 error:  ',num2str(app.M1,'%2.6f'));
                            %res{end+1} = ;
                            assignin('base','M1uhel',app.u1');
                            %plot(axes1,1:length(app.u1'),app.u1');
                        case 2
                            [app.M2,app.u2,time]=datasetTestingFunctionSingle(app.AHRS2,app.dataset,app.RMS,app.IMU, app.exceptPeak);
                            res{end+1} =strcat('Method 2 error: ',num2str(app.M2));
                            assignin('base','M2uhel',app.u2');
                            assignin('base','ahrs2',app.AHRS2');
                        case 3
                            [app.M3,app.u3,time]=datasetTestingFunctionSingle(app.AHRS3,app.dataset,app.RMS,app.IMU, app.exceptPeak);
                            res{end+1} =strcat('Method 3 error: ',num2str(app.M3));
                            assignin('base','M3uhel',app.u3');
                        case 4
                            [app.M4,app.u4,time]=datasetTestingFunctionSingle(app.AHRS4,app.dataset,app.RMS,app.IMU, app.exceptPeak);
                            res{end+1} =strcat('Method 4 error: ',num2str(app.M4));
                            assignin('base','M4uhel',app.u4');
                    end
                end
            end
            assignin('base','time',time);
            %             PlotErrors;
            app.TextArea.Value=res;
        end

        % Value changed function: DropDown
        function methodOneChanged(app, event)
            value = app.DropDown.Value;
            app.errorProgress1=0;
            app.intProg1=1;
            
            switch(value)
                case 'MadgwickModif'
                    app.AHRS1 = MadgwickAHRS3();
                case 'Madgwick'
                    app.AHRS1 = MadgwickAHRSclanek();
                case 'JustaFast'
                    app.AHRS1 = JustaAHRSPureFast();
                case 'JustaPure'
                    app.AHRS1 = JustaAHRSPure();
                case 'JustaLinear'
                    app.AHRS1 = JustaAHRSPureFastLinearCorr();
                case 'JustaConstant'
                    app.AHRS1 = JustaAHRSPureFastConstantCorr();
                case 'Valenti'
                    app.AHRS1 = Valenti_AHRS();
                case 'Guo'
                    app.AHRS1 = JinWuKF_AHRSreal2();
                case 'YoungSooSuh'
                    app.AHRS1 = YoungSooSuh_AHRS();
            end
        end

        % Value changed function: DropDown2
        function methodTwoChanged(app, event)
            value = app.DropDown2.Value;
            
            app.errorProgress2=0;
            app.intProg2=1;
            switch(value)
                case 'MadgwickModif'
                    app.AHRS2 = MadgwickAHRS3();
                case 'Madgwick'
                    app.AHRS2 = MadgwickAHRSclanek();
                case 'JustaLinear'
                    app.AHRS2 = JustaAHRSPureFastLinearCorr();
                case 'JustaConstant'
                    app.AHRS2 = JustaAHRSPureFastConstantCorr();
                case 'JustaFast'
                    app.AHRS2 = JustaAHRSPureFast();
                case 'JustaPure'
                    app.AHRS2 = JustaAHRSPure();
                case 'Valenti'
                    app.AHRS2 = Valenti_AHRS();
                case 'Guo'
                    app.AHRS2 = JinWuKF_AHRSreal2();
                case 'YoungSooSuh'
                    app.AHRS2 = YoungSooSuh_AHRS();
            end
        end

        % Button pushed function: Button2
        function OptimizeM1(app, event)
            
            app.Button2.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS1,e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS1,app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                app.errorProgress1(app.intProg1)=e;
                app.intProg1=app.intProg1+1;
                
                plot(app.UIAxes,1:length(app.errorProgress1),app.errorProgress1);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,1:length(app.errorProgress2),app.errorProgress2);
                plot(app.UIAxes,1:length(app.errorProgress3),app.errorProgress3);
                plot(app.UIAxes,1:length(app.errorProgress4),app.errorProgress4);
                hold(app.UIAxes,'off');
                plot(app.UIAxes2,1:length(app.errorProgress1),app.errorProgress1);
                
                app.NumericEditField.Value=param(1);
                app.NumericEditField2.Value=param(2);
                app.NumericEditField3.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button2.Text='Optimize';
            %             ylabel(app.UIAxes,'muj modrej')
            %             hold(app.UIAxes);
            %             plot(app.UIAxes,movmean(uhelMadg(1:(myEnd-start)),300));
            %             holdoff(app.UIAxes);
        end

        % Button pushed function: Button4
        function stop(app, event)
            app.stopOpt=1;
        end

        % Button pushed function: Button3
        function OptimizeM2(app, event)
            app.Button3.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS2,e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS2,app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                
                app.errorProgress2(app.intProg2)=e;
                app.intProg2=app.intProg2+1;
                plot(app.UIAxes,1:length(app.errorProgress2),app.errorProgress2);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,1:length(app.errorProgress1),app.errorProgress1);
                plot(app.UIAxes,1:length(app.errorProgress3),app.errorProgress3);
                plot(app.UIAxes,1:length(app.errorProgress4),app.errorProgress4);
                hold(app.UIAxes,'off');
                plot(app.UIAxes3,1:length(app.errorProgress2),app.errorProgress2);
                
                app.NumericEditField4.Value=param(1);
                app.NumericEditField5.Value=param(2);
                app.NumericEditField6.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button3.Text='Optimize';
        end

        % Value changed function: DropDown3
        function datasetChanged(app, event)
            value = app.DropDown3.Value;
            switch(value)
                case 'Set1'
                    app.dataset=1;
                case 'Set2'
                    app.dataset=2;
                case 'Set12'
                    app.dataset=3;
                case 'Set123'
                    app.dataset=4;
                case 'ALSdataset'
                    app.dataset=5;
                case 'Synthetic2'
                    app.dataset=6;
                case 'Synthetic3'
                    app.dataset=7;
            end
            app.errorProgress1=0;
            app.intProg1=1;
            app.errorProgress2=0;
            app.intProg2=1;
            app.errorProgress3=0;
            app.intProg3=1;
            app.errorProgress4=0;
            app.intProg4=1;
        end

        % Value changed function: CheckBox
        function RMSchanged(app, event)
            app.RMS = app.CheckBox.Value;
            app.errorProgress1=0;
            app.intProg1=1;
            app.errorProgress2=0;
            app.intProg2=1;
            
            app.errorProgress3=0;
            app.intProg3=1;
            app.errorProgress4=0;
            app.intProg4=1;
        end

        % Value changed function: NumericEditField4
        function valChanged2(app, event)
            
        end

        % Value changed function: NumericEditField
        function valChanged1(app, event)
            
        end

        % Value changed function: DropDown4
        function Method3Changed(app, event)
            value = app.DropDown4.Value;
            
            app.errorProgress3=0;
            app.intProg3=1;
            switch(value)
                case 'MadgwickModif'
                    app.AHRS3 = MadgwickAHRS3();
                case 'Madgwick'
                    app.AHRS3 = MadgwickAHRSclanek();
                case 'JustaFast'
                    app.AHRS3 = JustaAHRSPureFast();
                case 'JustaPure'
                    app.AHRS3 = JustaAHRSPure();
                
                case 'JustaLinear'
                    app.AHRS3 = JustaAHRSPureFastLinearCorr();
                case 'JustaConstant'
                    app.AHRS3 = JustaAHRSPureFastConstantCorr();
                case 'Valenti'
                    app.AHRS3 = Valenti_AHRS();
                case 'Guo'
                    app.AHRS3 = JinWuKF_AHRSreal2();
                case 'YoungSooSuh'
                    app.AHRS3 = YoungSooSuh_AHRS();
                case 'Wilson'
                    app.AHRS3 = Wilson_Madgwick_AHRS();
                    
            end
        end

        % Value changed function: DropDown5
        function Method4Changed(app, event)
            value = app.DropDown5.Value;
            app.errorProgress4=0;
            app.intProg4=1;
            switch(value)
                case 'MadgwickModif'
                    app.AHRS4 = MadgwickAHRS3();
                case 'Madgwick'
                    app.AHRS4 = MadgwickAHRSclanek();
                case 'JustaFast'
                    app.AHRS4 = JustaAHRSPureFast();
                case 'JustaPure'
                    app.AHRS4 = JustaAHRSPure();
                case 'JustaLinear'
                    app.AHRS4 = JustaAHRSPureFastLinearCorr();
                case 'JustaConstant'
                    app.AHRS4 = JustaAHRSPureFastConstantCorr();
                case 'Valenti'
                    app.AHRS4 = Valenti_AHRS();
                case 'Guo'
                    app.AHRS4 = JinWuKF_AHRSreal2();
                case 'YoungSooSuh'
                    app.AHRS4 = YoungSooSuh_AHRS();
                case 'Admirall'
                    app.AHRS4 = AdmirallWilsonAHRS();
            end
        end

        % Button pushed function: Button5
        function OptimizeM3(app, event)
            app.Button5.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS3,e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS3,app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                
                app.errorProgress3(app.intProg3)=e;
                app.intProg3=app.intProg3+1;
                plot(app.UIAxes,1:length(app.errorProgress3),app.errorProgress3);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,1:length(app.errorProgress1),app.errorProgress1);
                plot(app.UIAxes,1:length(app.errorProgress2),app.errorProgress2);
                plot(app.UIAxes,1:length(app.errorProgress4),app.errorProgress4);
                hold(app.UIAxes,'off');
                plot(app.UIAxes4,1:length(app.errorProgress3),app.errorProgress3);
                
                app.NumericEditField7.Value=param(1);
                app.NumericEditField8.Value=param(2);
                app.NumericEditField9.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button5.Text='Optimize';
        end

        % Button pushed function: Button6
        function OptimizeM4(app, event)
            app.Button6.Text='Wait';
            app.Button4.Text= 'Stop Optimization';
            while(true)
                [app.AHRS4,e,param,app.optimAttempts,app.optimFreezeParam]=optimFilterParams(app.AHRS4,app.RMS,app.dataset,app.optimAttempts,app.optimFreezeParam,app.IMU);
                
                app.errorProgress4(app.intProg4)=e;
                app.intProg4=app.intProg4+1;
                plot(app.UIAxes,1:length(app.errorProgress4),app.errorProgress4);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,1:length(app.errorProgress1),app.errorProgress1);
                plot(app.UIAxes,1:length(app.errorProgress2),app.errorProgress2);
                plot(app.UIAxes,1:length(app.errorProgress3),app.errorProgress3);
                hold(app.UIAxes,'off');
                plot(app.UIAxes5,1:length(app.errorProgress4),app.errorProgress4);
                
                app.NumericEditField10.Value=param(1);
                app.NumericEditField11.Value=param(2);
                app.NumericEditField12.Value=param(3);
                pause(0.5);
                if(app.stopOpt)
                    app.Button4.Text = '';
                    app.stopOpt=0;
                    break;
                end
            end
            app.optimAttempts=0;
            app.optimFreezeParam=0;
            
            app.Button6.Text='Optimize';
        end

        % Value changed function: CheckBox7
        function IMUchange(app, event)
            value = app.CheckBox7.Value;
            app.IMU=value;
        end

        % Value changed function: CheckBox8
        function ExceptChange(app, event)
            app.exceptPeak=app.CheckBox8.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Compare AHRS method GUI';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @startApp, true);
            app.Button.Text = 'Start test';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Campare optimization')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Method1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @methodOneChanged, true);
            app.DropDown.Value = 'MadgwickModif';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Method2';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @methodTwoChanged, true);
            app.DropDown2.Value = 'JustaFast';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM1, true);
            app.Button2.Text = 'Optimize';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM2, true);
            app.Button3.Text = 'Optimize';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.NumericEditField = uieditfield(app.Panel, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @valChanged1, true);
            app.NumericEditField2 = uieditfield(app.Panel, 'numeric');
            app.NumericEditField3 = uieditfield(app.Panel, 'numeric');
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.Button4.Text = '';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @valChanged2, true);
            app.NumericEditField5 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField6 = uieditfield(app.Panel2, 'numeric');
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel';
            app.ChosedatasetLabel = uilabel(app.Panel3);
            app.ChosedatasetLabel.Text = 'Chose dataset';
            app.DropDown3 = uidropdown(app.Panel3);
            app.DropDown3.Items = {'Set1', 'Set2', 'Set12', 'Set123', 'ALSdataset', 'Synthetic2', 'Synthetic3'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @datasetChanged, true);
            app.DropDown3.Value = 'Set1';
            app.CheckBox = uicheckbox(app.Panel3);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @RMSchanged, true);
            app.CheckBox.Tooltip = {'Mean square error'};
            app.CheckBox.Text = 'MSE';
            app.CheckBox2 = uicheckbox(app.Panel3);
            app.CheckBox2.Tooltip = {'The error estimated from euler angles.'};
            app.CheckBox2.Text = 'Euler';
            app.CheckBox7 = uicheckbox(app.Panel3);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @IMUchange, true);
            app.CheckBox7.Tooltip = {'Only accelerometer and gyro filter.'};
            app.CheckBox7.Text = 'IMU';
            app.CheckBox8 = uicheckbox(app.Panel3);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @ExceptChange, true);
            app.CheckBox8.Tooltip = {'There is significant error peak with all filters in set 2, which can be caused by desynchronization of reference data. Check to remove this peak from error estimation.'};
            app.CheckBox8.Text = 'Except Peak';
            app.TextArea = uitextarea(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Method 1')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Method 2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM3, true);
            app.Button5.Text = 'Optimize';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @OptimizeM4, true);
            app.Button6.Text = 'Optimize';
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Panel';
            app.NumericEditField7 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField8 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField9 = uieditfield(app.Panel4, 'numeric');
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'Panel';
            app.NumericEditField10 = uieditfield(app.Panel5, 'numeric');
            app.NumericEditField11 = uieditfield(app.Panel5, 'numeric');
            app.NumericEditField12 = uieditfield(app.Panel5, 'numeric');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Method3';
            app.DropDown4 = uidropdown(app.UIFigure);
            app.DropDown4.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh', 'Wilson'};
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @Method3Changed, true);
            app.DropDown4.Value = 'Valenti';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Method4';
            app.DropDown5 = uidropdown(app.UIFigure);
            app.DropDown5.Items = {'Madgwick', 'JustaFast', 'Valenti', 'Guo', 'MadgwickModif', 'JustaPure', 'JustaLinear', 'JustaConstant', 'YoungSooSuh', 'Admirall'};
            app.DropDown5.ValueChangedFcn = createCallbackFcn(app, @Method4Changed, true);
            app.DropDown5.Value = 'Guo';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Method 3')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Method 4')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.UIFigure);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.UIFigure);
            app.CheckBox5.Text = '';
            app.CheckBox6 = uicheckbox(app.UIFigure);
            app.CheckBox6.Text = '';
            app.IncludeintestLabel = uilabel(app.UIFigure);
            app.IncludeintestLabel.Text = {'Include'; 'in test'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end